classdef TieTech_A_Final_Draft < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UpperHorizontalThresholdDefault00035EditField  matlab.ui.control.NumericEditField
        UpperHorizontalThresholdDefault00035EditFieldLabel  matlab.ui.control.Label
        LowerHorizontalThresholdDefault0002EditField  matlab.ui.control.NumericEditField
        LowerHorizontalThresholdDefault0002EditFieldLabel  matlab.ui.control.Label
        VerticalThresholdDefault0003EditField  matlab.ui.control.NumericEditField
        VerticalThresholdDefault0003EditFieldLabel  matlab.ui.control.Label
        BalanceButton       matlab.ui.control.Button
        ExportSaveButton    matlab.ui.control.Button
        NameEditField       matlab.ui.control.EditField
        NameEditFieldLabel  matlab.ui.control.Label
        STOPButton          matlab.ui.control.Button
        STARTButton         matlab.ui.control.Button
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        aUno %arduino
        stop; % for stop button
        complete = 0; % for stop button
        rtg1 = 0; %Real time graph for A1(beam 1)
        rtg2; %Real time graph for A2(beam 2)
        rtg3; %Real time graph for A3(beam 3)
        rtg4; %Real time graph for A4(beam 4)
        rtg5;
        rtg6;
        tSecs; %For time calculation
        t_log; %log of times for data
        a1S_log; %log of beam 1 strain data
        a2S_log; %log of beam 2 strain data
        a3S_log; %log of beam 3 strain data
        a4S_log; %log of beam 4 strain data
        sum_vert_log;
        sum_horiz_log;
        results = []; %variable that will hold time and strain data
        x1; % balance variable for beam 1
        x2; % balance variable for beam 2
        x3; % balance variable for beam 3 
        x4; % balance variable for beam 4
    end
   
    methods (Access = private)
        
        function port = detectArduinoPort(app)
            % Get list of available serial ports
            ports = serialportlist("available");
            port = '';  % default if not found

            for i = 1:length(ports)
                try
                    a = arduino(ports(i), 'Uno'); % Adjust baud rate if needed
                    port = ports(i);
                    clear a
                    break;
                catch
                    continue;
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = groot().ScreenSize;
            appWidth = 1200;  % Desired width of the window
            appHeight = 800;  % Desired height of the window
        
            left = (screenSize(3) - appWidth) / 2;
            bottom = (screenSize(4) - appHeight) / 2;
        
            app.UIFigure.Position = [left, bottom, appWidth, appHeight];

            d = uifigure('Name','Initializing...','Position',[500 500 300 100],'WindowStyle','modal');
            lbl = uilabel(d, 'Text','Initializing App. Please wait...','FontSize',14,'Position',[20 40 260 20]);

            port = detectArduinoPort(app);

            if port ~= ""
                clear app.aUno;
                app.aUno = arduino(port, 'Uno');  % Save to a property if needed
                close(d)
            else
                uialert(app.UIFigure, 'No Arduino detected. Check the connection and try again.', 'Connection Error');
                close(d)
                pause(5)
                delete(app);
            end
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
        if isempty(app.x1) %If the user pushes start before balance this forces the user to choose balance before pressing start
            uialert(app.UIFigure, 'Please click Balance before starting.', 'Balance Required'); 
            return;
        end       

        cla(app.UIAxes);
        cla(app.UIAxes2);
        app.complete = 0;

        tic;  % Initialize startTime when the button is pressed

        app.rtg1 = animatedline(app.UIAxes, 'Color', 'b', 'LineWidth',2); % Horizontal / Vertical
      
        app.rtg2 = animatedline(app.UIAxes2, 'Color', 'r','LineWidth',2); % Horizontal / Vertical

        app.rtg3 = [];
        app.rtg4 = [];
        app.rtg5 = [];
        app.rtg6 = [];
     
        % Define threshold value (easy to change here) %This will be that
        % value that users should not exceed while using the board 
        if app.VerticalThresholdDefault0003EditField.Value == 0
            strainThresholdV = 0.003;
        else
            strainThresholdV = app.VerticalThresholdDefault0003EditField.Value;
        end
      
        if app.LowerHorizontalThresholdDefault0002EditField.Value == 0
            strainThresholdH_d = 0.002;
        else
            strainThresholdH_d = app.LowerHorizontalThresholdDefault0002EditField.Value;
        end

        if app.UpperHorizontalThresholdDefault00035EditField.Value == 0
            strainThresholdH = 0.0035;
        else
            strainThresholdH = app.UpperHorizontalThresholdDefault00035EditField.Value;
        end

        % Add to both graphs (lines that show where threshold is on the graph
        yline(app.UIAxes, strainThresholdV, '--r', 'Threshold', 'LabelHorizontalAlignment', 'left'); 
        yline(app.UIAxes2, strainThresholdH, '--r', 'Threshold', 'LabelHorizontalAlignment', 'left');
        yline(app.UIAxes2, strainThresholdH_d, '--g', 'Lower Threshold', 'LabelHorizontalAlignment', 'left');
        

        legend(app.UIAxes, {'Beam 1'});
        legend(app.UIAxes2, {'Beam 3'});

        app.UIAxes.YGrid = 'on';

        app.UIAxes2.YGrid = 'on';
        
        app.stop = false;

        while ~app.stop
            %% Read in Voltage
            a1V =readVoltage(app.aUno,'A1'); % reads in Voltage for one beam through A1 pin
            a2V =readVoltage(app.aUno,'A2'); % reads in Voltage for one beam through A2 pin
            a3V =readVoltage(app.aUno,'A3'); % reads in Voltage for one beam through A3 pin
            a4V =readVoltage(app.aUno,'A4'); % reads in Voltage for one beam through A4 pin
    
            %% voltage to strain to force 
            % voltage to strain (This section takes the voltage values and covnerts them to strain
            a1S = 2*(a1V-app.x1)/(2.1*5);
            a2S = 2*(a2V-app.x2)/(2.1*5);
            a3S = 2*(a3V-app.x3)/(2.1*5);
            a4S = 2*(a4V-app.x4)/(2.1*5);
       
            % strain to force (This section take the strain values and  converts them to strain

            %% Current Time
            live_t = toc; %This represents the running test time that appears on the graphs
            
           %% Acquire data points to be plot
            addpoints(app.rtg1, live_t, sum([abs(a1S), abs(a2S)])); %This adds data from beams 1 and 2 to a plot
            addpoints(app.rtg2, live_t, sum([abs(a3S), abs(a4S)])); %This adds data from beams 3 and 4 to a plot 
            app.rtg3(end+1) = a1S;
            app.rtg4(end+1) = a2S;
            app.rtg5(end+1) = a3S;
            app.rtg6(end+1) = a4S;

            %% Plot points on axes
            ax.XLim = [0,live_t];
            %datetick('x','keeplimits')

            drawnow
            app.stop = app.complete;

        end 


        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.rtg1 == 0
                uialert(app.UIFigure, 'Please run a test before pressing Stop', 'Start Button not Pressed');
                return;
            end
            app.complete = 1;
          
            %% Get Data
            [app.t_log,app.sum_vert_log] = getpoints(app.rtg1);
            [app.t_log,app.sum_horiz_log] = getpoints(app.rtg2);

            app.tSecs = (app.t_log-app.t_log(1))*24*3600;

            %% Fill table with Data

            app.results = table(app.t_log',app.rtg3',app.rtg4',app.rtg5',app.rtg6', app.sum_vert_log', app.sum_horiz_log','VariableNames',{'Time(s)','Vertical (1)','Vertical (2)','Horizontal (3)','Horizontal (4)','Vertical Sum','Horizontal Sum'})

            %% Clear Balance Data to require Re-Balance for each test
            app.x1 = [];
            app.x2 = [];
            app.x3 = [];
            app.x4 = [];
            app.rtg1 = 0;

        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            
        end

        % Button pushed function: ExportSaveButton
        function ExportSaveButtonPushed(app, event)
            if app.complete == 0
                uialert(app.UIFigure, 'Please collect data before exporting', 'Start Button not Pressed');
                return;
            end  
            
            defaultDate = datetime('today');
            operator = app.NameEditField.Value;

            % Ask user to select a directory
            folder = uigetdir('', 'Select Folder to Save File');
            if isequal(folder, 0)
                return; % User cancelled
            end

            % Create a search pattern to count iterations
            filePattern = fullfile(folder, sprintf('%s_%s_Iter*.xlsx', operator, defaultDate));
            existingFiles = dir(filePattern);
            nextIter = length(existingFiles) + 1;
            iterStr = sprintf('_%02d', nextIter);

            % Create final file name
            fileName = sprintf('%s_%s_%s.xlsx', operator, defaultDate, iterStr);
            fullPath = fullfile(folder, fileName);

             % Write the table to file
            writetable(app.results, fullPath);

            % Notify user
            uialert(app.UIFigure, ['Data saved to: ' fullPath], 'Export Complete');
        end

        % Button pushed function: BalanceButton
        function BalanceButtonPushed(app, event)
            if app.rtg1 ~= 0
                
            else
                % Get name and date from input fields
                operator = app.NameEditField.Value;
                if isempty(operator)
                    uialert(app.UIFigure, 'Please enter a name before balancing.', 'Missing Operator Name');
                    return;
                end
    
                
                d = uifigure('Name','Balancing...','Position',[500 500 300 100],'WindowStyle','modal');
                lbl = uilabel(d, 'Text','Balancing gauges. Please wait...','FontSize',14,'Position',[20 40 260 20]);
    
                drawnow;
                app.x1 = 0;
                app.x2 = 0;
                app.x3 = 0;
                app.x4 = 0;
                m = 10;
                for i = 1:m
                    b1 =readVoltage(app.aUno,'A1'); % reads in Voltage for one beam through A1 pin
                    b2 =readVoltage(app.aUno,'A2');
                    b3 =readVoltage(app.aUno,'A3');
                    b4 =readVoltage(app.aUno,'A4');
    
                    app.x1 = app.x1+b1;
                    app.x2 = app.x2+b2;
                    app.x3 = app.x3+b3;
                    app.x4 = app.x4+b4;

                    pause(0.1);
    
                end
                app.x1 = app.x1/m;
                app.x2 = app.x2/m;
                app.x3 = app.x3/m;
                app.x4 = app.x4/m;
    
                close(d);
    
                uialert(app.UIFigure, 'Strain gauges balanced. You may now start the test.', 'Balance Complete');
            end
        end

        % Value changed function: VerticalThresholdDefault0003EditField
        function VerticalThresholdDefault0003EditFieldValueChanged(app, event)
            value = app.VerticalThresholdDefault0003EditField.Value;
            
        end

        % Value changed function: 
        % LowerHorizontalThresholdDefault0002EditField
        function LowerHorizontalThresholdDefault0002EditFieldValueChanged(app, event)
            value = app.LowerHorizontalThresholdDefault0002EditField.Value;
            
        end

        % Value changed function: 
        % UpperHorizontalThresholdDefault00035EditField
        function UpperHorizontalThresholdDefault00035EditFieldValueChanged(app, event)
            value = app.UpperHorizontalThresholdDefault00035EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vertical (Y)')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Strain')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Horizontal (X)')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Strain')
            zlabel(app.UIAxes2, 'Z')
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.ExportSaveButton = uibutton(app.UIFigure, 'push');
            app.ExportSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSaveButtonPushed, true);
            app.ExportSaveButton.Text = 'Export & Save';
            app.BalanceButton = uibutton(app.UIFigure, 'push');
            app.BalanceButton.ButtonPushedFcn = createCallbackFcn(app, @BalanceButtonPushed, true);
            app.BalanceButton.Text = 'Balance';
            app.VerticalThresholdDefault0003EditFieldLabel = uilabel(app.UIFigure);
            app.VerticalThresholdDefault0003EditFieldLabel.Text = 'Vertical Threshold (Default 0.003)';
            app.VerticalThresholdDefault0003EditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticalThresholdDefault0003EditField.ValueChangedFcn = createCallbackFcn(app, @VerticalThresholdDefault0003EditFieldValueChanged, true);
            app.LowerHorizontalThresholdDefault0002EditFieldLabel = uilabel(app.UIFigure);
            app.LowerHorizontalThresholdDefault0002EditFieldLabel.Text = 'Lower Horizontal Threshold (Default 0.002)';
            app.LowerHorizontalThresholdDefault0002EditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerHorizontalThresholdDefault0002EditField.ValueChangedFcn = createCallbackFcn(app, @LowerHorizontalThresholdDefault0002EditFieldValueChanged, true);
            app.UpperHorizontalThresholdDefault00035EditFieldLabel = uilabel(app.UIFigure);
            app.UpperHorizontalThresholdDefault00035EditFieldLabel.Text = 'Upper Horizontal Threshold (Default 0.0035)';
            app.UpperHorizontalThresholdDefault00035EditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperHorizontalThresholdDefault00035EditField.ValueChangedFcn = createCallbackFcn(app, @UpperHorizontalThresholdDefault00035EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TieTech_A_Final_Draft

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end